classdef HyperCubeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HyperCubeAppUIFigure   matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        MainTab                matlab.ui.container.Tab
        GridLayout             matlab.ui.container.GridLayout
        Spectral_PreviewPanel  matlab.ui.container.Panel
        SpectralAxes           matlab.ui.control.UIAxes
        RGB_PreviewPanel       matlab.ui.container.Panel
        RGBAxes                matlab.ui.control.UIAxes
        ExportSpectrumButton   matlab.ui.control.Button
        NameOfHyperCube        matlab.ui.control.Label
        ExportRGBButton        matlab.ui.control.Button
        LoadDataButton         matlab.ui.control.Button
        SpectralSliceTab       matlab.ui.container.Tab
        GridLayout2            matlab.ui.container.GridLayout
        ExportSliceButton      matlab.ui.control.Button
        SpectralEditField      matlab.ui.control.NumericEditField
        nmEditFieldLabel       matlab.ui.control.Label
        ColormapDropDown       matlab.ui.control.DropDown
        ColormapDropDownLabel  matlab.ui.control.Label
        SpectralSlider         matlab.ui.control.Slider
        SpectralSlicePanel     matlab.ui.container.Panel
        SliceAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        HyperCubeInstance % Instance třídy HyperCube
        filepath  % Cesta ke složce s fotkami
    end
    
    methods (Access = private)
        
        function onImageClick(app, evt)
            if isempty(app.HyperCubeInstance), return; end
            cp = evt.IntersectionPoint;
            x = round(cp(1)); y = round(cp(2));
            
            if x<1 || y<1 || x>app.HyperCubeInstance.x_dim || y>app.HyperCubeInstance.y_dim
                return;
            end

            spec = app.HyperCubeInstance.GetSpectrum(x, y);
            axS = app.SpectralAxes;
            cla(axS);
            plot(axS, spec.x, spec.y, 'LineWidth', 1.2);

            % osa X: podle dat (typicky 400–701), dynamicky
            axS.XLim = [min(spec.x) max(spec.x)];   % Nastaví rozsah spektrální osy na základě rozsahu z metody GetSpectrum()
            axS.XTickMode = 'auto';     % Přizpůsobí tics λ podle úrovně zoomu

            % osa Y: vždy 0–255
            axS.YLim = [0 255];         
            axS.YTickMode = 'auto';     % Přizpůsobí tics I podle úrovně zoomu
            grid(axS, 'on');

            xlabel(axS, 'λ [nm]');
            ylabel(axS, 'Intensity [-]');
            title(axS, sprintf('Spectrum at [%d,%d]', x, y));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            folder = uigetdir(pwd, 'Vyber složku s BMP');   % Uživatel vybere složku z Exploreru
            if isequal(folder, 0), return; end
            [~, folderName] = fileparts(folder);
            app.NameOfHyperCube.Text = folderName;
            
            d = uiprogressdlg(app.HyperCubeAppUIFigure, ...
                'Title','Načítám data', ...
                'Message','Prosím čekejte...', ...
                'Indeterminate','on');
            app.LoadDataButton.Enable = 'off';

            try
                app.HyperCubeInstance = HyperCube(folder);
                rgbImage = app.HyperCubeInstance.GetAsRGB();

                ax = app.RGBAxes;
                cla(ax);
                hImg = image(ax, 'CData', rgbImage);   % vykreslení
                axis(ax, 'image');
                ax.YDir = 'reverse';                   % řádek 1 nahoře
                ax.XTick = []; ax.YTick = [];
                ax.XColor = 'none'; ax.YColor = 'none';
                title(ax, '');

                % Načtení Hodnot do Tabu Spectral Slice
                SpectralSliderTicks = app.HyperCubeInstance.lambda_axis;
                app.SpectralSlider.Limits = [min(SpectralSliderTicks) max(SpectralSliderTicks)];
                app.SpectralSlider.MajorTicks = SpectralSliderTicks;

                % řidší popisky: např. každý 8. tick popsaný, ostatní prázdné
                step = 15;
                labels = strings(size(SpectralSliderTicks));
                labels(1:step:end) = string(SpectralSliderTicks(1:step:end));  % nebo sprintfc('%.0f', ...)
                app.SpectralSlider.MajorTickLabels = labels;
                
                app.SpectralSlider.Value = SpectralSliderTicks(1);
                app.SpectralSlider.Enable = 'on';

                % Načtení limitů do SpectralEditField na Spectral Slice
                app.SpectralEditField.Limits = app.SpectralSlider.Limits;
                app.SpectralEditField.Value  = app.SpectralSlider.Value;
                app.SpectralEditField.LowerLimitInclusive = "on";
                app.SpectralEditField.UpperLimitInclusive = "on";


                % kliknutí na obrázek => spektrum
                set(hImg, 'ButtonDownFcn', @(src, evt) onImageClick(app, evt), ...
                          'PickableParts', 'all', 'HitTest', 'on');
            catch ME
                uialert(app.HyperCubeAppUIFigure, ME.message, 'Chyba při načítání');
            end
            close(d);
            app.LoadDataButton.Enable = 'on';
        end

        % Value changed function: SpectralSlider
        function SpectralSliderValueChanged(app, event)
            if isempty(app.HyperCubeInstance), return; end

                lambda = event.Value;                     % hodnota slideru (λ)
                % pokud GetSlice bere index, pak:
                % [~, idx] = min(abs(app.HyperCubeInstance.lambda_axis - lambda));
                % slice = app.HyperCubeInstance.GetSlice(idx);
                slice = app.HyperCubeInstance.GetSlice(lambda);
        
                ax = app.SliceAxes;
                cla(ax);
                img = uint8(slice);                    % normalizace 0..1 pro zobrazení
                image(ax, 'CData', img);
                axis(ax, 'image');
                ax.YDir = 'reverse';
                ax.XTick = []; ax.YTick = [];
                ax.XColor = 'none'; ax.YColor = 'none';
                colormap(app.SliceAxes, app.ColormapDropDown.Value);
        
                app.SpectralEditField.Value = lambda;   % sync do fieldu
            
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            cm = event.Value;
            colormap(app.SliceAxes, cm);
            
        end

        % Value changed function: SpectralEditField
        function SpectralEditFieldValueChanged(app, event)
            if isempty(app.HyperCubeInstance), return; end
            val = event.Value;
        
            % zajisti, že hodnota je v rozsahu slideru
            lo = app.SpectralSlider.Limits(1);
            hi = app.SpectralSlider.Limits(2);
            val = min(max(val, lo), hi);
        
            % posuň slider a vykresli slice
            app.SpectralSlider.Value = val;
            SpectralSliderValueChanged(app, struct('Value', val));  % reuse callback
            
        end

        % Button pushed function: ExportRGBButton
        function ExportRGBButtonPushed(app, event)
            % najdi aktuální image v RGBAxes
            hImg = findobj(app.RGBAxes, 'Type', 'image');
            if isempty(hImg)
                uialert(app.HyperCubeAppUIFigure, 'Není co exportovat.', 'Export RGB');
                return;
            end
            rgb = hImg.CData;
            assignin('base', 'ExportedRGB', rgb);
            % volitelně i uložení do souboru:
            % imwrite(rgb, 'export_rgb.png');
        end

        % Button pushed function: ExportSpectrumButton
        function ExportSpectrumButtonPushed(app, event)
            % najdi vykreslenou křivku ve SpectralAxes
            hLine = findobj(app.SpectralAxes, 'Type', 'line');
            if isempty(hLine)
                uialert(app.HyperCubeAppUIFigure, 'Není co exportovat.', 'Export spektra');
                return;
            end
            X = hLine.XData;
            Y = hLine.YData;
            % jako struct:
            assignin('base','ExportedSpectrum', struct('x',X,'y',Y));
        end

        % Button pushed function: ExportSliceButton
        function ExportSliceButtonPushed(app, event)
            hImg = findobj(app.SliceAxes, 'Type', 'image');
            if isempty(hImg)
                uialert(app.HyperCubeAppUIFigure, 'Není co exportovat.', 'Export slice');
                return;
            end
            sliceImg = hImg.CData;
            assignin('base', 'ExportedSlice', sliceImg);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HyperCubeAppUIFigure = uifigure('Visible', 'off');
            app.HyperCubeAppUIFigure.Name = 'HyperCubeApp';
            app.HyperCubeAppUIFigure.Icon = fullfile(pathToMLAPP, 'AppGraphics', 'Icon1', 'android', 'res', 'mipmap-xxhdpi', 'ic_launcher.png');
            app.TabGroup = uitabgroup(app.HyperCubeAppUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.GridLayout = uigridlayout(app.MainTab);
            app.LoadDataButton = uibutton(app.GridLayout, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ExportRGBButton = uibutton(app.GridLayout, 'push');
            app.ExportRGBButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRGBButtonPushed, true);
            app.ExportRGBButton.Text = 'Export RGB Image';
            app.NameOfHyperCube = uilabel(app.GridLayout);
            app.NameOfHyperCube.Text = '';
            app.ExportSpectrumButton = uibutton(app.GridLayout, 'push');
            app.ExportSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSpectrumButtonPushed, true);
            app.ExportSpectrumButton.Text = 'Export Spectrum';
            app.RGB_PreviewPanel = uipanel(app.GridLayout);
            app.RGB_PreviewPanel.Title = 'RGB';
            app.RGBAxes = uiaxes(app.RGB_PreviewPanel);
            app.RGBAxes.XTick = [];
            app.RGBAxes.XTickLabel = '';
            app.RGBAxes.YTick = [];
            app.RGBAxes.YTickLabel = '';
            app.RGBAxes.ZTick = [];
            app.Spectral_PreviewPanel = uipanel(app.GridLayout);
            app.Spectral_PreviewPanel.Title = 'Spectrum';
            app.SpectralAxes = uiaxes(app.Spectral_PreviewPanel);
            app.SpectralAxes.XTick = [];
            app.SpectralAxes.YTick = [];
            app.SpectralAxes.ZTick = [];
            app.SpectralSliceTab = uitab(app.TabGroup);
            app.SpectralSliceTab.Title = 'Spectral Slice';
            app.GridLayout2 = uigridlayout(app.SpectralSliceTab);
            app.SpectralSlicePanel = uipanel(app.GridLayout2);
            app.SpectralSlicePanel.Title = 'Spectral Slice';
            app.SliceAxes = uiaxes(app.SpectralSlicePanel);
            app.SliceAxes.XTick = [];
            app.SliceAxes.YTick = [];
            app.SliceAxes.ZTick = [];
            app.SpectralSlider = uislider(app.GridLayout2);
            app.SpectralSlider.MajorTicks = [];
            app.SpectralSlider.ValueChangedFcn = createCallbackFcn(app, @SpectralSliderValueChanged, true);
            app.ColormapDropDownLabel = uilabel(app.GridLayout2);
            app.ColormapDropDownLabel.Text = 'Colormap:';
            app.ColormapDropDown = uidropdown(app.GridLayout2);
            app.ColormapDropDown.Items = {'gray', 'parula', 'jet', 'hot', 'turbo', 'bone', 'nebula', 'autumn', 'pink'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'gray';
            app.nmEditFieldLabel = uilabel(app.GridLayout2);
            app.nmEditFieldLabel.Text = 'λ [nm]:';
            app.SpectralEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SpectralEditField.ValueChangedFcn = createCallbackFcn(app, @SpectralEditFieldValueChanged, true);
            app.ExportSliceButton = uibutton(app.GridLayout2, 'push');
            app.ExportSliceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSliceButtonPushed, true);
            app.ExportSliceButton.Text = 'Export Slice';
            % Show the figure after all components are created
            app.HyperCubeAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HyperCubeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HyperCubeAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HyperCubeAppUIFigure)
        end
    end
end